classdef CharacterMaps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CharacterFileEditFieldLabel  matlab.ui.control.Label
        CharacterFileEditField       matlab.ui.control.EditField
        SelectFileButton             matlab.ui.control.Button
        SaveBtn                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        button_colors = [0 0 0; 0.94 0.94 0.94;]; % The two button colors
        bg % button group
        btn % 
        characters % list of characters
        numRows % Description
        numCols % Description
        numPixels % Description
        pxlStartX % Description
        pxlStartY % Description
        pxlBtns % Description
        selectedBtn % Description
        mapLabel % Description
    end
    
    methods (Access = private)
        
        function results = toggleButtonColor(app,event)
            if event.Source.BackgroundColor(1) == app.button_colors(1,1) ...
                && event.Source.BackgroundColor(2) == app.button_colors(1,2)...
                && event.Source.BackgroundColor(3) == app.button_colors(1,3)
                event.Source.BackgroundColor = app.button_colors(2,:);
            else
                event.Source.BackgroundColor = app.button_colors(1,:);
            end
            
        end
        
        function results = drawButtonGroup(app)
            % Create bg
            
            bg_height = 560;
            bg_width = 250;
            bg_x = 16;
            bg_x_delta = 50;
            bg_y = app.UIFigure.Position(4)-100-bg_height;
            app.bg = uibuttongroup(app.UIFigure);
            app.bg.Title = 'Characters';
            app.bg.Position = [bg_x bg_y bg_width bg_height];
            app.bg.SelectionChangedFcn = @app.myBgSelectionChanged;
            
            % Create btns
            btnStartHeight = bg_height - 50;
            btnStartX = 10;
            col_spacing = 60;
            row_spacing = 20;
            col = 1;
            row = 1;
            x = btnStartX;
            y = btnStartHeight;
            for i = 1:length(app.characters)
                if y <= 0
                    col = col + 1;
                    row = 1;
                    x = (btnStartX+col_spacing*(col-1));
                    y =  btnStartHeight - row_spacing*(row-1);
                end
                lbtn(i) = uiradiobutton(app.bg);
                lbtn(i).Text = app.characters(i).chr;
                lbtn(i).Position = [x y 58 20];
                lbtn(i).Value = true;
                lbtn(i).Tag = sprintf('%d',i);
                row = row + 1;
                x = (btnStartX+col_spacing*(col-1));
                y = btnStartHeight - row_spacing*(row-1);
            end

            app.btn = lbtn;
        end
        
        function results = readCharacterMapFile(app,fileName)
            fileId = fopen(fileName);
            c = textscan(fileId,'%s %s %s %s %s %s 0x%2x, 0x%2x, 0x%2x, 0x%2x, 0x%2x %s');
            fclose(fileId);
            s = split(c{4},'[');
            for i = 1:length(c{1})
                app.characters(i).chr = s{i,1};
                app.characters(i).map = [c{7}(i); c{8}(i); c{9}(i); c{10}(i); c{11}(i);]';
            end
        end
        
        function results = myBgSelectionChanged(app,src,event)
            app.selectedBtn.character = src.SelectedObject.Text;
            app.selectedBtn.bgIdx = str2num(src.SelectedObject.Tag);
            drawCharacter(app,src);
            updateMapLabel(app);
        end
        
        function results = drawCharacter(app, src)
            c = src.SelectedObject.Text;
            ind = findCharacterIdx(app, c);
            row = 1;
            col = 1;
            for i = 1:app.numPixels
                p = bitget(app.characters(ind).map(col),row);
                if p == 0
                    app.pxlBtns(i).BackgroundColor = app.button_colors(2,:);
                else
                    app.pxlBtns(i).BackgroundColor = app.button_colors(1,:);
                end
                row = row+1;
                if row > app.numRows
                    row = 1;
                    col = col +1;
                end
            end
        end
        
        function results = createPixelMap(app)
            app.numRows = 8;
            app.numCols = 5;
            app.numPixels = app.numRows * app.numCols;
            app.pxlStartX = app.bg.Position(1) + app.bg.Position(3) + 150;
            app.pxlStartY = app.bg.Position(2) + app.bg.Position(4) - 50;
            x = app.pxlStartX;
            y = app.pxlStartY;
            row = 1;
            col = 1;
            for i = 1:app.numPixels
                lbtn(i) = uibutton(app.UIFigure, 'push');
                %lbtn(i).ButtonPushedFcn = createCallbackFcn(app, @pxlBtn_Pushed, true);
                lbtn(i).ButtonPushedFcn = @app.myPxlButtonPushedFcn;
                lbtn(i).Tag = sprintf('Row=%d, Col=%d', row, col);
                lbtn(i).BackgroundColor = [0 0 0];
                lbtn(i).Position = [x y 20 20];
                lbtn(i).Text = '';
                if row == app.numRows
                    lbtn(i).Enable = 'off';
                end
                row = row + 1;
                if row > app.numRows
                    row = 1;
                    col = col + 1;
                end
                x = app.pxlStartX + 20*(col-1);
                y = app.pxlStartY - 20*(row-1);
            end
            app.pxlBtns = lbtn;
        end
        
        function results = myPxlButtonPushedFcn(app,src,event)
            toggleButtonColor(app,event);
            updateCharacterMap(app,src,event);
        end
        
        function results = findCharacterIdx(app, c)
            for i = 1:length(app.characters)
                if strcmp(app.characters(i).chr, c)
                    break;
                end
            end
            results = i;
        end
        
        function results = updateCharacterMap(app,src,event)
            rc = rowColFromTag(app, src.Tag);
            btnIdx = app.selectedBtn.bgIdx;
            if event.Source.BackgroundColor(1) == app.button_colors(1,1) ...
                && event.Source.BackgroundColor(2) == app.button_colors(1,2)...
                && event.Source.BackgroundColor(3) == app.button_colors(1,3)
                m = bitset(app.characters(btnIdx).map(rc.col),rc.row,1);
                app.characters(btnIdx).map(rc.col) = m;
            else
                m = bitset(app.characters(btnIdx).map(rc.col),rc.row,0);
                app.characters(btnIdx).map(rc.col) = m;
            end
            updateMapLabel(app)  
        end
        
        function results = rowColFromTag(app, s)
            p = split(s,["=", ","]);
            results.row = str2double(p(2));
            results.col = str2double(p(4));
            
            
        end
        
        function results = findBgIdx(app,c)
            i = 1;
            while (app.btn(i).Text ~= c) && (i <= length(app.btn))
                i = i + 1;
            end
            if i <= length(app.btn)
                results = i;
            else
                results = 0;
            end
            
        end
        
        function results = createMapLabel(app)
            x = app.pxlBtns(8).Position(1);
            y = app.pxlBtns(8).Position(2)-30;
            w = 200;
            h = 20;
            app.mapLabel = uilabel(app.UIFigure);
            app.mapLabel.Position = [x, y, w, h];
            
        end
        
        function results = updateMapLabel(app)
            btnIdx = str2num(app.bg.SelectedObject.Tag);
            app.mapLabel.Text = sprintf(' %02x  %02x  %02x  %02x  %02x', app.characters(btnIdx).map);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [filepath, name, ext] = fileparts(mfilename('fullpath'))
            CharacterMapFile = [filepath '\CharacterMap.txt'];
            app.CharacterFileEditField.Value = CharacterMapFile;
            readCharacterMapFile(app,CharacterMapFile);
            drawButtonGroup(app);
            createPixelMap(app);
            createMapLabel(app);
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            if ~isempty(app.CharacterFileEditField.Value)
               [filepath, name, ext] = fileparts(app.CharacterFileEditField.Value);
               [file,path] = uigetfile('*.txt','Select Character Map File', filepath);
            else
                [file,path] = uigetfile('*.txt','Select Character Map File');
            end
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.CharacterFileEditField.Value = fullfile(path,file);
               f = fullfile(path,file);
               readCharacterMapFile(app,f);
            end
        end

        % Button pushed function: SaveBtn
        function SaveBtnButtonPushed(app, event)
            fileID = fopen(app.CharacterFileEditField.Value,'w');
            %fileID = fopen('C:\Users\jwaite\OneDrive - Wavetronix LLC\Documents\Matlab\CharacterMaps\CharacterMapTemp.txt','w');
            if fileID < 0 
                f = msgbox('Could not open file.');
            else
                for i=1:length(app.characters)
                    fprintf(fileID,'static const uint8_t %s[5] = { 0x%02X, 0x%02X, 0x%02X, 0x%02X, 0x%02X };\n', app.characters(i).chr, app.characters(i).map(1), app.characters(i).map(2), app.characters(i).map(3), app.characters(i).map(4), app.characters(i).map(5) )
                end
                fclose(fileID);
            end
            
            %app.CharacterFileEditField.Value
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fig = app.UIFigure;
            msg = 'Save Character File?';
            title = 'Confirm Save';
            selection = uiconfirm(fig,msg,title,...
           'Options',{'Save', 'Do Not Save', 'Cancel'},...
           'DefaultOption',1,'CancelOption',3);
            
           switch(selection)
               case 'Save'
                   SaveBtnButtonPushed(app, event);
                   delete(app);
               case 'Do Not Save'
                    delete(app);
               case 'Cancel'
                   
               otherwise
                   
           end
    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CharacterFileEditFieldLabel = uilabel(app.UIFigure);
            app.CharacterFileEditFieldLabel.Text = 'Character File';
            app.CharacterFileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Visible = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnButtonPushed, true);
            app.SaveBtn.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CharacterMaps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end