classdef CharacterMaps2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveBtn                      matlab.ui.control.Button
        SelectFileButton             matlab.ui.control.Button
        CharacterFileEditField       matlab.ui.control.EditField
        CharacterFileEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        button_colors = [0 0 0; 0.94 0.94 0.94;]; % The two button colors
        bg % button group
        btn % 
        characters % list of characters
        numRows % Description
        numCols % Description
        numPixels % Description
        pxlStartX % Description
        pxlStartY % Description
        pxlBtns % Description
        selectedCharacterBtn % Description
        mapeditfield % Description
        maplabel % Description
    end
    
    methods (Access = private)
        
        function results = toggleButtonColor(app,event)
            if event.Source.BackgroundColor(1) == app.button_colors(1,1) ...
                && event.Source.BackgroundColor(2) == app.button_colors(1,2)...
                && event.Source.BackgroundColor(3) == app.button_colors(1,3)
                event.Source.BackgroundColor = app.button_colors(2,:);
            else
                event.Source.BackgroundColor = app.button_colors(1,:);
            end
            
        end
        
        function results = drawButtonGroup(app)
            % Create bg
            
            bg_height = 680;
            bg_width = 500;
            bg_x = 16;
            bg_x_delta = 50;
            bg_y = app.UIFigure.Position(4)-100-bg_height;
            app.bg = uibuttongroup(app.UIFigure);
            app.bg.Title = 'Characters';
            app.bg.Position = [bg_x bg_y bg_width bg_height];
            app.bg.SelectionChangedFcn = @app.myBgSelectionChanged;
            
            % Create btns
            btnStartHeight = bg_height - 50;
            btnStartX = 10;
            col_spacing = 110;
            row_spacing = 20;
            col = 1;
            row = 1;
            x = btnStartX;
            y = btnStartHeight;
            for i = 1:length(app.characters)
                if y <= 0
                    col = col + 1;
                    row = 1;
                    x = (btnStartX+col_spacing*(col-1));
                    y =  btnStartHeight - row_spacing*(row-1);
                end
                lbtn(i) = uiradiobutton(app.bg);
                %lbtn(i).Text = app.characters(i).chr;
                lbtn(i).Text = sprintf('0x%02X - %s', i-1, app.characters(i).chr);
                lbtn(i).Position = [x y 100 20];
                lbtn(i).Value = true;
                lbtn(i).Tag = sprintf('%d',i);
                row = row + 1;
                x = (btnStartX+col_spacing*(col-1));
                y = btnStartHeight - row_spacing*(row-1);
            end

            app.btn = lbtn;
        end
        
        function results = readCharacterMapFile(app,fileName)
            load('AsciiCharacters.mat', 'asciiCharacters')
            fileId = fopen(fileName);
            chrNum = 0;
            while true 
                d = fgetl(fileId);
                if strfind(d, 'Character')
                    break;
                end
            end
            s = split(d, ["[", "]"]);
            app.numRows = str2num(s{4});
            app.numCols = 7;
            app.numPixels = app.numRows * app.numCols;
            d = fgetl(fileId); % get openind '{'
            i = 1;
            chrNum = 0;
            prev_d = '';
            while true
                d = fgetl(fileId);
                if d == -1
                    break; % end of file
                end
                dtrim = strtrim(d);
                if isempty(d)
                    %do nothing
                elseif strcmp(dtrim(1),'{') == true
                    chrNum = chrNum + 1; %new character
                    app.characters(chrNum).chr = '';
                    i = 1;
                elseif contains(dtrim, '//')
                    s = split(d, ["//", "-", " "]);
                    app.characters(chrNum+1).chr = s{10};
                elseif contains(dtrim, ["/***", "***/"])
                    s = split(d, ["/*", "-", " ", "*/"]);
                    app.characters(chrNum+1).chr = s{12};
                elseif strcmp(dtrim(1),'}') == true
                    if strcmp(prev_d, d)
                        break;
                    end
                else
                    %app.characters(chrNum).chr = char(chrNum);
                    s = strtrim(split(strtrim(erase(d,',' ))));
                    n = hex2dec(s{1});
                    app.characters(chrNum).map(i) = n;
                    if strcmp(app.characters(chrNum).chr,'')
                        app.characters(chrNum).chr = asciiCharacters{chrNum};
                    end
                    i = i + 1;
                end
                prev_d = d; 

            end
            fclose(fileId);
        end
        
        function results = myBgSelectionChanged(app,src,event)
            %app.selectedCharacterBtn.character = src.SelectedObject.Text;
            %app.selectedCharacterBtn.bgIdx = str2num(src.SelectedObject.Tag);
            drawCharacter(app,src);
            updateMapLabel(app);
            updateMapEditField(app);
        end
        
        function results = drawCharacter(app, src)
            ind = str2double(src.SelectedObject.Tag);
            row = 1;
            col = 1;
            for i = 1:app.numPixels
                p = bitget(app.characters(ind).map(row),app.numCols -col+2);
                if p == 0
                    app.pxlBtns(i).BackgroundColor = app.button_colors(2,:);
                else
                    app.pxlBtns(i).BackgroundColor = app.button_colors(1,:);
                end
                col = col+1;
                if col > app.numCols
                    col = 1;
                    row = row +1;
                end
            end
        end
        
        function results = createPixelMap(app)
            app.pxlStartX = app.bg.Position(1) + app.bg.Position(3) + 50;
            app.pxlStartY = app.bg.Position(2) + app.bg.Position(4) - 20;
            x = app.pxlStartX;
            y = app.pxlStartY;
            row = 1;
            col = 1;
            for i = 1:app.numPixels
                lbtn(i) = uibutton(app.UIFigure, 'push');
                %lbtn(i).ButtonPushedFcn = createCallbackFcn(app, @pxlBtn_Pushed, true);
                lbtn(i).ButtonPushedFcn = @app.myPxlButtonPushedFcn;
                lbtn(i).Tag = sprintf('Row=%d, Col=%d', row, col);
                lbtn(i).BackgroundColor = [0 0 0];
                lbtn(i).Position = [x y 20 20];
                lbtn(i).Text = '';
                if row == app.numRows
                    %lbtn(i).Enable = 'off';
                end
                col = col + 1;
                if col > app.numCols
                    col = 1;
                    row = row + 1;
                end
                x = app.pxlStartX + 20*(col-1);
                y = app.pxlStartY - 20*(row-1);
            end
            app.pxlBtns = lbtn;
        end
        
        function results = myPxlButtonPushedFcn(app,src,event)
            toggleButtonColor(app,event);
            updateCharacterMap(app,src,event);
        end
        
        function results = findCharacterIdx(app, c)
            for i = 1:length(app.characters)
                if strcmp(app.characters(i).chr, c)
                    break;
                end
            end
            results = i;
        end
        
        function results = updateCharacterMap(app,src,event)
            rc = rowColFromTag(app, src.Tag);
            %btnIdx = app.selectedCharacterBtn.bgIdx;
            bgIdx = str2double(app.bg.SelectedObject.Tag);
            if event.Source.BackgroundColor(1) == app.button_colors(1,1) ...
                && event.Source.BackgroundColor(2) == app.button_colors(1,2)...
                && event.Source.BackgroundColor(3) == app.button_colors(1,3)
                m = bitset(app.characters(bgIdx).map(rc.row), (app.numCols - rc.col+2),1);
            else
                m = bitset(app.characters(bgIdx).map(rc.row), (app.numCols - rc.col+2),0);
            end
            m = bitset(m,1,0); %the unused bit
            app.characters(bgIdx).map(rc.row) = m;
            updateMapLabel(app);
        end
        
        function results = rowColFromTag(app, s)
            p = split(s,["=", ","]);
            results.row = str2double(p(2));
            results.col = str2double(p(4));
            
            
        end
        
        function results = findBgIdx(app,c)
            i = 1;
            while (app.btn(i).Text ~= c) && (i <= length(app.btn))
                i = i + 1;
            end
            if i <= length(app.btn)
                results = i;
            else
                results = 0;
            end
            
        end
        
        function results = createMapEditField(app)
            x = app.pxlBtns(1).Position(1);
            y = app.pxlBtns(end).Position(2)-30;
            w = 40;
            h = 20;
            app.mapeditfield = uieditfield(app.UIFigure, 'text');
            app.mapeditfield.Position = [x, y, w, h];
            app.mapeditfield.Visible = 'on';
            %app.bg.SelectionChangedFcn = @app.myBgSelectionChanged;
            %app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.mapeditfield.ValueChangedFcn = @app.mapEditFieldChangedFcn;
        end

        function results = createMapLabel(app)
            w = 100;
            h = 15*app.numRows;
            x = app.mapeditfield.Position(1);
            y = app.mapeditfield.Position(2)-10-h;
            app.maplabel = uilabel(app.UIFigure);
            app.maplabel.Position = [x, y, w, h];
        end
        
        function results = updateMapEditField(app)
            btnIdx = str2num(app.bg.SelectedObject.Tag);
            app.mapeditfield.Value = sprintf('%s', app.characters(btnIdx).chr);
        end

        function results = updateMapLabel(app)
            btnIdx = str2num(app.bg.SelectedObject.Tag);
            labelStr= '';
            for i = 1:length(app.characters(btnIdx).map)
                %labelStr = [labelStr sprintf('0x%02x',  app.characters(btnIdx).map(i)) '\n'];
                labelStr = sprintf('%s0x%02x\n',labelStr,app.characters(btnIdx).map(i));
            end
            
            app.maplabel.Text = labelStr;
        end


        function results = mapEditFieldChangedFcn(app,src,event)
            i = str2num(app.bg.SelectedObject.Tag);
            app.btn(i).Text = sprintf('0x%02X - %s', i-1, app.mapeditfield.Value);
            app.characters(i).chr = app.mapeditfield.Value;
        end
        
        function results = hexByte2VisualMap(app,b)
            s = '';
            for i = 1:7
                j = bitget(b,8-i+1);
                if j == 0
                    c = '.';
                else
                    c = '#';
                end
                s = strcat(s,c); 
            end
            results = s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 800 800];
            app.CharacterFileEditFieldLabel.Position = [39 (app.UIFigure.Position(3)-30) 68 22];
            app.CharacterFileEditField.Position = [122 (app.UIFigure.Position(3)-30) 600 22];
            app.SelectFileButton.Position = [600 (app.UIFigure.Position(3)-60) 89 22];
            app.SaveBtn.Position = [500 (app.UIFigure.Position(3)-60) 100 22];
            [filepath, name, ext] = fileparts(mfilename('fullpath'));
            CharacterMapFile = [filepath '\CharacterMap.c'];
            app.CharacterFileEditField.Value = CharacterMapFile;
            readCharacterMapFile(app,CharacterMapFile);
            drawButtonGroup(app);
            createPixelMap(app);
            createMapEditField(app);
            createMapLabel(app);
            updateMapEditField(app);
            updateMapLabel(app);
            updateMapEditField(app);
            set(app.bg,'SelectedObject',app.btn(1));
            myBgSelectionChanged(app,app.bg,0);
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            if ~isempty(app.CharacterFileEditField.Value)
               [filepath, name, ext] = fileparts(app.CharacterFileEditField.Value);
               [file,path] = uigetfile('*.c','Select Character Map File', filepath);
            else
                [file,path] = uigetfile('*.c','Select Character Map File');
            end
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.CharacterFileEditField.Value = fullfile(path,file);
               f = fullfile(path,file);
               readCharacterMapFile(app,f);
            end
        end

        % Button pushed function: SaveBtn
        function SaveBtnButtonPushed(app, event)
            fileID = fopen(app.CharacterFileEditField.Value,'w');
            %fileID = fopen('C:\Users\jwaite\OneDrive - Wavetronix LLC\Documents\Matlab\CharacterMaps\CharacterMapTemp.txt','w');
            if fileID < 0 
                f = msgbox('Could not open file.');
            else
                fprintf(fileID, '/**\n');
                fprintf(fileID, '  * @file   characters.c\n');
                fprintf(fileID, '  *\n');
                fprintf(fileID, '  * @brief contains character maps for text\n');
                fprintf(fileID, '  *\n');
                fprintf(fileID, '  * @author Jonathan Waite\n');
                fprintf(fileID, '  *\n');
                fprintf(fileID, '  * @note Test Project: Unit2\n');
                fprintf(fileID, '  *\n');
                fprintf(fileID, '  * @copyright 2020 Island Radar LLC\n');
                fprintf(fileID, '  */\n\n');
                fprintf(fileID, '#include \"characters.h\"\n\n');
                fprintf(fileID, 'const uint8_t Character[%d][%d] =\n\n', length(app.characters), app.numRows );
                for i=1:length(app.characters)
                    fprintf(fileID,'    /***  Char 0x%02X - %s  ***/\n', i-1, app.characters(i).chr);
                    fprintf(fileID, '    {\n' );
                    for j = 1:app.numRows
                        c = hexByte2VisualMap(app,app.characters(i).map(j));
                        if j ~= app.numRows
                            fprintf(fileID, '        0x%02X,    /*  %s  */\n', app.characters(i).map(j), c); 
                        else
                            fprintf(fileID, '        0x%02X     /*  %s  */\n', app.characters(i).map(j), c);
                        end
                    end
                    if i ~= length(app.characters)
                        fprintf(fileID, '    },\n' );
                    else
                        fprintf(fileID, '    }\n' );
                    end
                end
                fprintf(fileID, '};\n' );
                fclose(fileID);
            end
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fig = app.UIFigure;
            msg = 'Save Character File?';
            title = 'Confirm Save';
            selection = uiconfirm(fig,msg,title,...
           'Options',{'Save', 'Do Not Save', 'Cancel'},...
           'DefaultOption',1,'CancelOption',3);
            
           switch(selection)
               case 'Save'
                   SaveBtnButtonPushed(app, event);
                   delete(app);
               case 'Do Not Save'
                    delete(app);
               case 'Cancel'
                   
               otherwise
                   
           end
    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CharacterFileEditFieldLabel = uilabel(app.UIFigure);
            app.CharacterFileEditFieldLabel.Text = 'Character File';
            app.CharacterFileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnButtonPushed, true);
            app.SaveBtn.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CharacterMaps2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end