classdef ColorLightCameraSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        StopButton        matlab.ui.control.StateButton
        PlayButton        matlab.ui.control.StateButton
        FrameNumberLabel  matlab.ui.control.Label
        FocusKnob         matlab.ui.control.Knob
        FocusKnobLabel    matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        CameraAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cam = webcam(2); % Description
        img % Description
        frameTimer % Description
        frameNumber = 0; % Description
    end
    
    
    methods (Access = private)
        
        function results = frameTimerCallback(app)
            app.frameNumber = app.frameNumber + 1;
            app.FrameNumberLabel.Text = sprintf('Frame Number: %d', app.frameNumber);
            app.img = snapshot(app.cam);
            image(app.CameraAxes, app.img);

            results = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FocusKnob.Value = 50;
            app.img = snapshot(app.cam);
            image(app.CameraAxes, app.img);

            %setup up timer to get frames
            frames_per_second = 3;
            app.frameTimer = timer('BusyMode', 'drop', 'ExecutionMode', 'fixedRate', 'Period', (1/frames_per_second), 'TasksToExecute', inf);
            %app.frameTimer.TimerFcn = @app.frameTimerCallback;
            app.frameTimer.TimerFcn = @(~,~)app.frameTimerCallback;
            start(app.frameTimer);
        end

        % Value changed function: FocusKnob
        function FocusKnobValueChanged(app, event)
            value = app.FocusKnob.Value;
            app.cam.Focus = value;
        end

        % Callback function
        function PlayButtonPushed(app, event)
            app.StopButton
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 0
                breakhere = 1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {603, 603};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FocusKnobLabel = uilabel(app.LeftPanel);
            app.FocusKnobLabel.Text = 'Focus';
            app.FocusKnob = uiknob(app.LeftPanel, 'continuous');
            app.FocusKnob.ValueChangedFcn = createCallbackFcn(app, @FocusKnobValueChanged, true);
            app.FrameNumberLabel = uilabel(app.LeftPanel);
            app.FrameNumberLabel.Text = 'Frame Number: ';
            app.PlayButton = uibutton(app.LeftPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.LeftPanel, 'state');
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.CameraAxes = uiaxes(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorLightCameraSensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end