classdef HistogramViewApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DataDirectoryButton       matlab.ui.control.Button
        DirectoryLabel            matlab.ui.control.Label
        DirectoryEditField        matlab.ui.control.EditField
        StatusTextLabel           matlab.ui.control.Label
        StatusLabel_2             matlab.ui.control.Label
        BackgroundHistogramPanel  matlab.ui.container.Panel
        Selectupto5antennasPanel  matlab.ui.container.Panel
        CheckBox_1                matlab.ui.control.CheckBox
        CheckBox_2                matlab.ui.control.CheckBox
        CheckBox_3                matlab.ui.control.CheckBox
        CheckBox_4                matlab.ui.control.CheckBox
        CheckBox_5                matlab.ui.control.CheckBox
        CheckBox_6                matlab.ui.control.CheckBox
        CheckBox_7                matlab.ui.control.CheckBox
        CheckBox_8                matlab.ui.control.CheckBox
        CheckBox_9                matlab.ui.control.CheckBox
        CheckBox_10               matlab.ui.control.CheckBox
        CheckBox_11               matlab.ui.control.CheckBox
        CheckBox_12               matlab.ui.control.CheckBox
        CheckBox_13               matlab.ui.control.CheckBox
        CheckBox_14               matlab.ui.control.CheckBox
        CheckBox_15               matlab.ui.control.CheckBox
        CheckBox_16               matlab.ui.control.CheckBox
        SelectAllButton           matlab.ui.control.Button
        SelectNoneButton          matlab.ui.control.Button
        RangeBinsToPlotPanel      matlab.ui.container.Panel
        RangeBinsToPlot           matlab.ui.control.NumericEditField
        ThresholdsModePanel       matlab.ui.container.Panel
        ModeAxes                  matlab.ui.control.UIAxes
        AntennaEditFieldLabel     matlab.ui.control.Label
        AntennaEditField          matlab.ui.control.NumericEditField
        RangeEditFieldLabel       matlab.ui.control.Label
        RangeEditField            matlab.ui.control.NumericEditField
        FrameBack1                matlab.ui.control.Button
        FrameBackN                matlab.ui.control.Button
        FrameForward1             matlab.ui.control.Button
        FrameForwardN             matlab.ui.control.Button
        FrameEditFieldLabel       matlab.ui.control.Label
        FrameNum                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Data % Description
        BackgroundSubplot1 % Description
        BackgroundSubplot2 % Description
        BackgroundSubplot3 % Description
        BackgroundSubplot4 % Description
        BackgroundSubplot5 % Description
    end
    
    properties (Access = private)
    end

    
    methods (Access = public)
        
        function result = loadHistogramData(app)
            result = 0;
            cd(app.DirectoryEditField.Value);
            dataFiles = dir('*.mat');
            app.Data = [];
            numFiles = size(dataFiles,1); 
            for i = 1:numFiles
                result = i;
                d = load(dataFiles(i).name);
                if i == 1
                    % initialize
                    app.Data.numDataSets = 0;
                    app.Data.backgroundHist = zeros([numFiles size(d.backgroundHist)]); 
                    app.Data.daily_hist = zeros([numFiles size(d.daily_hist)]);
                    app.Data.clutter = zeros([numFiles size(d.clutter)]);
                    app.Data.hist_mode = zeros([numFiles size(d.hist_mode)]);
                    app.Data.pga = zeros(numFiles, 1);
                    app.Data.sensor_id = zeros(numFiles, 1); 
                    app.Data.pulse_count =zeros(numFiles, 1);
                    app.Data.seconds_on = zeros(numFiles, 1);
                    app.Data.year = zeros(numFiles, 1);
                    app.Data.month = zeros(numFiles, 1);
                    app.Data.day = zeros(numFiles, 1);
                    app.Data.hour = zeros(numFiles, 1);
                    app.Data.minute = zeros(numFiles, 1);
                    app.Data.second = zeros(numFiles, 1);
                end
                
                app.Data.numDataSets = i;
                app.Data.backgroundHist(i,:,:,:) = d.backgroundHist; 
                app.Data.daily_hist(i,:,:,:) = d.daily_hist;
                app.Data.clutter(i,:,:) = d.clutter;
                app.Data.hist_mode(i,:,:) = d.hist_mode;
                app.Data.pga(i,:) = d.pga;
                app.Data.sensor_id(i,:) = d.sensor_id;
                app.Data.pulse_count(i,:) = d.pulse_count;
                app.Data.seconds_on(i,:) = d.seconds_on;
                app.Data.year(i,:) = d.year;
                app.Data.month(i,:) = d.month;
                app.Data.day(i,:) = d.day;
                app.Data.hour(i,:) = d.hour;
                app.Data.minute(i,:) = d.minute;
                app.Data.second(i,:) = d.second;
                app.StatusTextLabel.Text = ['Data set: ' num2str(i) ' of ' num2str(numFiles)];
                drawnow
            end
             [maxVals, maxIdx] = max(app.Data.backgroundHist,[], 4);
            app.Data.my_hist_mode = maxIdx; %This is the mode
            
        end
        
        function results = updateHistogramsPlot(app)
            results = 0;
            frame = app.FrameNum.Value;
            fieldNames = fieldnames(app); % gets the name list for all uicomponents as cell array
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox_','IgnoreCase',false)); % gets only the names contains "CheckBox" specifically.

            subplotNum = 0;
            RangeBins = app.RangeEditField.Value + (1:(app.RangeBinsToPlot.Value));
            if min(RangeBins) < 0
                RangeBins = RangeBins - min(RangeBins);
            end
            if max(RangeBins) > 79
                RangeBins = RangeBins -(max(RangeBins)-79);
            end
            cla(app.BackgroundSubplot1);ylabel(app.BackgroundSubplot1, '');
            cla(app.BackgroundSubplot2);ylabel(app.BackgroundSubplot2, '');
            cla(app.BackgroundSubplot3);ylabel(app.BackgroundSubplot3, '');
            cla(app.BackgroundSubplot4);ylabel(app.BackgroundSubplot4, '');
            cla(app.BackgroundSubplot5);ylabel(app.BackgroundSubplot5, '');
            for i = 1:numel(allCheckBoxNamesList)
                if app.(allCheckBoxNamesList{i}).Value == true
                    subplotNum = subplotNum + 1;
                    antennaNum = str2num(cell2mat(regexp(app.(allCheckBoxNamesList{i}).Text,'\d+', 'Match')))+1;
                    switch(subplotNum)
                        case(1)
                            currentAxis = app.BackgroundSubplot1;
                        case(2)
                            currentAxis = app.BackgroundSubplot2;
                        case(3)
                            currentAxis = app.BackgroundSubplot3;
                        case(4)
                            currentAxis = app.BackgroundSubplot4;
                        case(5)
                            currentAxis = app.BackgroundSubplot5;
                        otherwise
                            currentAxis = app.BackgroundSubplot1; 
                    end

                    
                    hold(currentAxis,'on')
                    for binIdx = 1:numel(RangeBins)
                        plot(currentAxis,squeeze(app.Data.backgroundHist(frame,antennaNum,RangeBins(binIdx),:)), 'DisplayName', sprintf('Bin %d', (RangeBins(binIdx)-1)))
                    end
                    ylabel(currentAxis,{['Ant' num2str(antennaNum-1)]; ['Bins ' num2str(RangeBins-1)]})
                    if subplotNum == 1
                        legend(currentAxis)
                    end
                end
            end
                        
        end
        
    end
    
    methods (Access = private)
        
        function results = InitFrameControl(app)
            results = 0;
            app.FrameNum.Limits = [1 app.Data.numDataSets];
            app.RangeBinsToPlot.Limits = [1 5];
            FrameNumValueChanged(app)
        end
        
        function results = UpdateModePlot(app)
            results = 0;
            frame = app.FrameNum.Value;
            %plot(app.ModeAxes, [0 10], [frame frame]) 
            [X, Y] = meshgrid(0:79, 0:15);
            %surf(app.ModeAxes,X, Y ,squeeze(app.Data.hist_mode(frame,:,:)))
            surf(app.ModeAxes,X, Y ,squeeze(app.Data.my_hist_mode(frame,:,:)))
            view(app.ModeAxes, 0,90)
            dateString = sprintf('%4d-%02d-%02d %02d:%02d:%02d', app.Data.year(frame), app.Data.month(frame), app.Data.day(frame), app.Data.hour(frame), app.Data.minute(frame) ,app.Data.second(frame));
            title(app.ModeAxes, dateString)
            caxis(app.ModeAxes,[0 60])
            colorbar(app.ModeAxes)
            dcm_obj = datacursormode(app.UIFigure);
            set(dcm_obj,'DisplayStyle','datatip', 'SnapToDataVertex','on','Enable','on');
        end
        
        
        function uncheck = antennaCheckBoxControl(app, event)
            uncheck = 0;
            fieldNames = fieldnames(app); % gets the name list for all uicomponents as cell array
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox_','IgnoreCase',false)); % gets only the names contains "CheckBox" specifically.
            numChecked = 0;
            for i = 1:numel(allCheckBoxNamesList) % a for loop for set all checkbox components' values as true 
                if app.(allCheckBoxNamesList{i}).Value == true
                    numChecked = numChecked +1;
                end
            end
            if numChecked > 5
                uncheck = 1;
                for i = 1:numel(allCheckBoxNamesList) % a for loop for set all checkbox components' values as true 
                    if strcmp(app.(allCheckBoxNamesList{i}).Text, event.Source.Text)
                         app.(allCheckBoxNamesList{i}).Value = 0;
                         break;
                    end
                end    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.BackgroundHistogramPanel.AutoResizeChildren = 'off';
            app.BackgroundSubplot1 = subplot(5,1,1,'Parent',app.BackgroundHistogramPanel);
            app.BackgroundSubplot2 = subplot(5,1,2,'Parent',app.BackgroundHistogramPanel);
            app.BackgroundSubplot3 = subplot(5,1,3,'Parent',app.BackgroundHistogramPanel);
            app.BackgroundSubplot4 = subplot(5,1,4,'Parent',app.BackgroundHistogramPanel);
            app.BackgroundSubplot5 = subplot(5,1,5,'Parent',app.BackgroundHistogramPanel);
            numSubplots =5;
            topBottomMargin = 0.05;
            leftMargin = 0.05;
            rightMargin = .15;
            verticalSpacingMargin = 0.05;
            verticalPlotSize = (1-2*topBottomMargin - (numSubplots-1)*verticalSpacingMargin)/numSubplots;
            horizontalPlotSize = 1-leftMargin - rightMargin;
            app.BackgroundSubplot1.Position = [leftMargin (topBottomMargin+4*verticalPlotSize+4*verticalSpacingMargin) horizontalPlotSize verticalPlotSize];
            app.BackgroundSubplot2.Position = [leftMargin (topBottomMargin+3*verticalPlotSize+3*verticalSpacingMargin) horizontalPlotSize verticalPlotSize];
            app.BackgroundSubplot3.Position = [leftMargin (topBottomMargin+2*verticalPlotSize+2*verticalSpacingMargin) horizontalPlotSize verticalPlotSize];
            app.BackgroundSubplot4.Position = [leftMargin (topBottomMargin+1*verticalPlotSize+1*verticalSpacingMargin) horizontalPlotSize verticalPlotSize];
            app.BackgroundSubplot5.Position = [leftMargin (topBottomMargin+0*verticalPlotSize+0*verticalSpacingMargin) horizontalPlotSize verticalPlotSize];
            plot(app.BackgroundSubplot1,[1 2 3 4])
            plot(app.BackgroundSubplot2,[10 9 4 7])
        end

        % Button pushed function: DataDirectoryButton
        function DataDirectoryButtonPushed(app, event)
            directory = uigetdir;
            if  directory ~= 0
                app.DirectoryEditField.Value =  directory;
                DirectoryEditFieldValueChanged(app, event)
            end
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            %value = app.DirectoryEditField.Value;
            loadHistogramData(app)
            result = InitFrameControl(app);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            fieldNames = fieldnames(app); % gets the name list for all uicomponents as cell array
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox_','IgnoreCase',false)); % gets only the names contains "CheckBox" specifically.
            for i = 1:numel(allCheckBoxNamesList) % a for loop for set all checkbox components' values as true 
                app.(allCheckBoxNamesList{i}).Value = true; % dynamic naming to reach elements under my app. 
            end
        end

        % Button pushed function: SelectNoneButton
        function SelectNoneButtonPushed(app, event)
            fieldNames = fieldnames(app); % gets the name list for all uicomponents as cell array
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox_','IgnoreCase',false)); % gets only the names contains "CheckBox" specifically.
            for i = 1:numel(allCheckBoxNamesList) % a for loop for set all checkbox components' values as true 
                app.(allCheckBoxNamesList{i}).Value = false; % dynamic naming to reach elements under my app. 
            end         
        end

        % Button pushed function: FrameForward1
        function FrameForward1ButtonPushed(app, event)
            newFrame = app.FrameNum.Value + 1;
            if newFrame > app.Data.numDataSets
                newFrame = app.Data.numDataSets;
            end
            app.FrameNum.Value = newFrame;
            FrameNumValueChanged(app);
        end

        % Button pushed function: FrameForwardN
        function FrameForwardNButtonPushed(app, event)
            newFrame = app.FrameNum.Value + 5;
            if newFrame > app.Data.numDataSets
                newFrame = app.Data.numDataSets;
            end
            app.FrameNum.Value = newFrame;
            FrameNumValueChanged(app);
        end

        % Button pushed function: FrameBack1
        function FrameBack1ButtonPushed(app, event)
            newFrame = app.FrameNum.Value - 1;
            if newFrame < 1
                newFrame = 1;
            end
            app.FrameNum.Value = newFrame;
            FrameNumValueChanged(app);
        end

        % Button pushed function: FrameBackN
        function FrameBackNButtonPushed(app, event)
            newFrame = app.FrameNum.Value - 5;
            if newFrame < 1
                newFrame = 1;
            end
            app.FrameNum.Value = newFrame;
            FrameNumValueChanged(app);
        end

        % Value changed function: FrameNum
        function FrameNumValueChanged(app, event)
            results = updateHistogramsPlot(app);
            results = UpdateModePlot(app);
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if 0
                temp = app.ModeAxes.CurrentPoint; % Returns 2x3 array of points
                mouseLocation = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
                if app.ModeAxes.XLim(1) <= mouseLocation(1) && mouseLocation(1) <= app.ModeAxes.XLim(2) ...
                        && app.ModeAxes.YLim(1) <= mouseLocation(2) && mouseLocation(2) <= app.ModeAxes.YLim(2)
                   app.AntennaEditField.Value = round(mouseLocation(2));
                   app.RangeEditField.Value = round(mouseLocation(1));
                   AntennaEditFieldValueChanged(app)
                   %RangeEditFieldValueChanged(app) %commented out because we don't need to call updateHistogramPlot twice
                end
            end
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
          antennaCheckBoxControl(app,event);
          updateHistogramsPlot(app);  
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            antennaCheckBoxControl(app, event);
            updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
          uncheck = antennaCheckBoxControl(app,event);
          updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
             antennaCheckBoxControl(app, event);
             updateHistogramsPlot(app);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            antennaCheckBoxControl(app, event);
            updateHistogramsPlot(app);
        end

        % Value changed function: AntennaEditField
        function AntennaEditFieldValueChanged(app, event)
            value = app.AntennaEditField.Value;
            
            updateHistogramsPlot(app);
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            value = app.RangeEditField.Value;
             updateHistogramsPlot(app);
        end

        % Value changed function: RangeBinsToPlot
        function RangeBinsToPlotValueChanged(app, event)
            value = app.RangeBinsToPlot.Value;
            updateHistogramsPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.DataDirectoryButton = uibutton(app.UIFigure, 'push');
            app.DataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DataDirectoryButtonPushed, true);
            app.DataDirectoryButton.Text = 'Data Directory';
            app.DirectoryLabel = uilabel(app.UIFigure);
            app.DirectoryLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.StatusTextLabel = uilabel(app.UIFigure);
            app.StatusTextLabel.Text = 'Status Text';
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Status:';
            app.BackgroundHistogramPanel = uipanel(app.UIFigure);
            app.BackgroundHistogramPanel.Title = 'Background Histogram';
            app.Selectupto5antennasPanel = uipanel(app.BackgroundHistogramPanel);
            app.Selectupto5antennasPanel.Title = 'Select up to 5 antennas';
            app.CheckBox_1 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = 'Ant0';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'Ant1';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = 'Ant2';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = 'Ant3';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = 'Ant4';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = 'Ant5';
            app.CheckBox_7 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = 'Ant6';
            app.CheckBox_8 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = 'Ant7';
            app.CheckBox_9 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = 'Ant8';
            app.CheckBox_10 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = 'Ant9';
            app.CheckBox_11 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = 'Ant10';
            app.CheckBox_12 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = 'Ant11';
            app.CheckBox_13 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = 'Ant12';
            app.CheckBox_14 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = 'Ant13';
            app.CheckBox_15 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = 'Ant14';
            app.CheckBox_16 = uicheckbox(app.Selectupto5antennasPanel);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Text = 'Ant15';
            app.SelectAllButton = uibutton(app.Selectupto5antennasPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Visible = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.SelectNoneButton = uibutton(app.Selectupto5antennasPanel, 'push');
            app.SelectNoneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNoneButtonPushed, true);
            app.SelectNoneButton.Text = 'Select None';
            app.RangeBinsToPlotPanel = uipanel(app.BackgroundHistogramPanel);
            app.RangeBinsToPlotPanel.Title = 'Range Bins To Plot';
            app.RangeBinsToPlot = uieditfield(app.RangeBinsToPlotPanel, 'numeric');
            app.RangeBinsToPlot.ValueChangedFcn = createCallbackFcn(app, @RangeBinsToPlotValueChanged, true);
            app.RangeBinsToPlot.Value = 3;
            app.ThresholdsModePanel = uipanel(app.UIFigure);
            app.ThresholdsModePanel.Title = 'Thresholds - Mode';
            app.ModeAxes = uiaxes(app.ThresholdsModePanel);
            title(app.ModeAxes, 'Title')
            xlabel(app.ModeAxes, '')
            ylabel(app.ModeAxes, '')
            app.AntennaEditFieldLabel = uilabel(app.ThresholdsModePanel);
            app.AntennaEditFieldLabel.Text = 'Antenna';
            app.AntennaEditField = uieditfield(app.ThresholdsModePanel, 'numeric');
            app.AntennaEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaEditFieldValueChanged, true);
            app.AntennaEditField.Enable = 'off';
            app.RangeEditFieldLabel = uilabel(app.ThresholdsModePanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.ThresholdsModePanel, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.FrameBack1 = uibutton(app.UIFigure, 'push');
            app.FrameBack1.ButtonPushedFcn = createCallbackFcn(app, @FrameBack1ButtonPushed, true);
            app.FrameBack1.Text = '<';
            app.FrameBackN = uibutton(app.UIFigure, 'push');
            app.FrameBackN.ButtonPushedFcn = createCallbackFcn(app, @FrameBackNButtonPushed, true);
            app.FrameBackN.Text = '<<';
            app.FrameForward1 = uibutton(app.UIFigure, 'push');
            app.FrameForward1.ButtonPushedFcn = createCallbackFcn(app, @FrameForward1ButtonPushed, true);
            app.FrameForward1.Text = '>';
            app.FrameForwardN = uibutton(app.UIFigure, 'push');
            app.FrameForwardN.ButtonPushedFcn = createCallbackFcn(app, @FrameForwardNButtonPushed, true);
            app.FrameForwardN.Text = '>>';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameNum = uieditfield(app.UIFigure, 'numeric');
            app.FrameNum.ValueChangedFcn = createCallbackFcn(app, @FrameNumValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistogramViewApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end