classdef Test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileEditFieldLabel       matlab.ui.control.Label
        FileName                 matlab.ui.control.EditField
        SelectFileButton         matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        TimeMagnitudeTab         matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        DisplayButtonGroup       matlab.ui.container.ButtonGroup
        TimeDataButton           matlab.ui.control.RadioButton
        MagnitudeResponseButton  matlab.ui.control.RadioButton
        RangeMaxAmplitudeButton  matlab.ui.control.RadioButton
        SpeedCorrelationTab      matlab.ui.container.Tab
        FFTPulseStrideLabel      matlab.ui.control.Label
        FftPulseStride           matlab.ui.control.NumericEditField
        SpeedFFTSizeLabel        matlab.ui.control.Label
        SpeedFftSize             matlab.ui.control.NumericEditField
        UIAxes2                  matlab.ui.control.UIAxes
        CorrelationThresholdEditFieldLabel  matlab.ui.control.Label
        CorrelationThreshold     matlab.ui.control.NumericEditField
        PlotBins1                matlab.ui.control.CheckBox
        PlotBins2                matlab.ui.control.CheckBox
        SpeedCorrelationAllTab   matlab.ui.container.Tab
    end


    properties (Access = private)
        z % container that holds data
        maxFftBin % The range bin where the train is at
        maxFft % max fft data across time
        %speedFftSize = 512;
        %speedFftStride = 256;
        speedCor % Description
        speedCorAllBins % Description
        corMaxMy1 % Description
        corMaxMy2
        corMaxIdxMy
        corMaxAllBinsMy1 % Description
        corMaxAllBinsMy2
        corMaxIdxAllBinsMy
        DIST_BETWEEN_ANTS =(83.32855682e-6); %miles
        hoursPerPrimaryPulse = (279e-6)*2/3600; %hours
        speed
        speedAllBins
        pulseIdxs % array containing pulse count indexes for speed correlations
    end

    methods (Access = private)
    
        function results = plotTimeData(app)
            title(app.UIAxes, 'Time Data')
            xlabel(app.UIAxes, 'Pulses')
            ylabel(app.UIAxes, 'Magnitude')
            plot(app.UIAxes, squeeze(app.z.dataMatrix(:,1,1:100)))
        end
        function results = plotMagnitudeResponse(app)
            title(app.UIAxes, 'Magnitude Response')
            xlabel(app.UIAxes, 'Pulses')
            ylabel(app.UIAxes, 'Magnitude')
            plot(app.UIAxes, squeeze(20*log10(abs(app.z.DataMatrix(app.maxFftBin,1,:)))), 'b')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, squeeze(20*log10(abs(app.z.DataMatrix(app.maxFftBin,2,:)))), 'g')
            hold(app.UIAxes, 'off')
            set(app.UIAxes2, 'xLimMode', 'manual')
            set(app.UIAxes2, 'xlim', [0 size(app.z.DataMatrix,3)])
        end
        function results = plotRangeMaxAmplitude(app)
            title(app.UIAxes, 'Range Max Amplitude')
            xlabel(app.UIAxes, 'Bin')
            ylabel(app.UIAxes, 'Max Amplitude')
            plot(app.UIAxes, 20*log10(app.maxFft))
        end
        function results = plotSpeedCorrelation(app)
            title(app.UIAxes2, 'Speed')
            xlabel(app.UIAxes2, 'Pulse')
            ylabel(app.UIAxes2, 'MPH')
            plot(app.UIAxes2, app.pulseIdxs, app.speed)
            set(app.UIAxes2, 'ylim', [-40 40])
            set(app.UIAxes2, 'xLimMode', 'manual')
            set(app.UIAxes2, 'xlim', [0 size(app.z.DataMatrix,3)])
        end

        function results = processData(app)
            %find bin index for train
            app.maxFft = max(abs(squeeze(app.z.DataMatrix(:,1,:))),[],2);
            starBin = 5;
            [mx, app.maxFftBin] = max(app.maxFft(starBin:end));
            app.maxFftBin = app.maxFftBin + starBin - 1;


        end
        
        function results = calcSpeedCor(app)
            pulseStart = 1;
            pulseStop = size(app.z.DataMatrix,3);
            app.pulseIdxs = pulseStart:app.FftPulseStride.Value:(pulseStop-app.SpeedFftSize.Value);
            app.speedCor = zeros(app.SpeedFftSize.Value, length(app.pulseIdxs));
            app.speedCorAllBins = zeros(app.SpeedFftSize.Value, size(app.z.DataMatrix,1), length(app.pulseIdxs));
            app.corMaxMy1 = zeros(size(app.pulseIdxs));
            app.corMaxMy2 = zeros(size(app.pulseIdxs));
            app.corMaxIdxMy = zeros(size(app.pulseIdxs));
            app.corMaxAllBinsMy1 = zeros(size(app.speedCorAllBins,2),size(app.pulseIdxs,2));
            app.corMaxAllBinsMy2 = zeros(size(app.speedCorAllBins,2),size(app.pulseIdxs,2));
            app.corMaxIdxAllBinsMy = zeros(size(app.speedCorAllBins,2),size(app.pulseIdxs,2));
            %(single fft bin)
            a=0;
            for i = app.pulseIdxs
                a = a+1;
                app.speedCor(:,a)= speedCorrelation(app.z.DataMatrix(app.maxFftBin, 1, i:(i+app.SpeedFftSize.Value-1)), app.z.DataMatrix(app.maxFftBin, 2, i:(i+app.SpeedFftSize.Value-1)));
                %find largest two peaks 
                p1 = 0;
                p1idx = 0;
                p2 = 0;
                p2idx = 0;
                for j = 2:(app.SpeedFftSize.Value-1)
                    if (abs(app.speedCor(j-1,a)) < abs(app.speedCor(j,a))) && (abs(app.speedCor(j,a)) > abs(app.speedCor(j+1,a)))
                        %peak found
                        if abs(app.speedCor(j,a)) > p1
                            p2 = p1;
                            p2idx = p1idx;
                            p1 = abs(app.speedCor(j,a));
                            p1idx = j;
                        elseif abs(app.speedCor(j,a)) > p2
                            p2 = abs(app.speedCor(j,a));
                            p2idx = j;
                        end
                    end
                end
                app.corMaxMy1(a) = p1;
                app.corMaxMy2(a) = p2;
                if p2 < (app.CorrelationThreshold.Value*p1)
                    app.corMaxIdxMy(a) = p1idx;
                else
                    app.corMaxIdxMy(a) = nan;
                end
            end
            
            %(all fft bins)
            a=0;
            for i = app.pulseIdxs
                a = a+1;
                for j = 1:size(app.speedCorAllBins,2)
                    app.speedCorAllBins(:,j,a)= speedCorrelation(app.z.DataMatrix(j, 1, i:(i+app.SpeedFftSize.Value-1)), app.z.DataMatrix(j, 2, i:(i+app.SpeedFftSize.Value-1)));
                    %find largest two peaks 
                    p1 = 0;
                    p1idx = 0;
                    p2 = 0;
                    p2idx = 0;
                    for k = 2:(app.SpeedFftSize.Value-1)
                        if (abs(app.speedCorAllBins(k-1,j,a)) < abs(app.speedCorAllBins(k,j,a))) && (abs(app.speedCorAllBins(k,j,a)) > abs(app.speedCorAllBins(k+1,j,a)))
                            %peak found
                            if abs(app.speedCorAllBins(k,j,a)) > p1
                                p2 = p1;
                                p2idx = p1idx;
                                p1 = abs(app.speedCorAllBins(k,j,a));
                                p1idx = k;
                            elseif abs(app.speedCorAllBins(k,j,a)) > p2
                                p2 = abs(app.speedCorAllBins(k,j,a));
                                p2idx = k;
                            end
                        end
                    end
                    app.corMaxAllBinsMy1(j,a) = p1;
                    app.corMaxAllBinsMy2(j,a) = p2;
                    if p2 < (app.CorrelationThreshold.Value*p1)
                        app.corMaxIdxAllBinsMy(a) = p1idx;
                    else
                        app.corMaxIdxAllBinsMy(a) = nan;
                    end

                end
            end
        end
        
        function results = calcSpeed(app)
           app.speed = zeros(size(app.corMaxIdxMy));
            for i = 1:length(app.corMaxIdxMy)
                if app.corMaxIdxMy(i) < app.SpeedFftSize.Value/2
                    app.speed(i) = app.DIST_BETWEEN_ANTS / ((app.corMaxIdxMy(i) +0.5) *app.hoursPerPrimaryPulse);
                else
                    app.speed(i) = app.DIST_BETWEEN_ANTS / ((app.corMaxIdxMy(i)-app.SpeedFftSize.Value +0.5) *app.hoursPerPrimaryPulse);
                end
            end
        end

        function results = calcSpeedAllBins(app)
           app.speedAllBins = zeros(size(app.corMaxIdxAllBinsMy));
            for i = 1:length(app.corMaxIdxAllBinsMy)
                if app.corMaxIdxAllBinsMy(i) < app.SpeedFftSize.Value/2
                    app.speedAllBins(i) = app.DIST_BETWEEN_ANTS / ((app.corMaxIdxAllBinsMy(i) +0.5) *app.hoursPerPrimaryPulse);
                else
                    app.speedAllBins(i) = app.DIST_BETWEEN_ANTS / ((app.corMaxIdxAllBinsMy(i)-app.SpeedFftSize.Value +0.5) *app.hoursPerPrimaryPulse);
                end
            end
        end

    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                myPathAndFile = getpref('myScriptSettings', 'lastPathAndFile')
            catch ME
               if ME.identifier == "" 
                   myPathAndFile = 'C:\';
                   setpref('myScriptSettings', 'lastPathAndFile', myPathAndFile)
               end
            end
            app.FileName.Value = myPathAndFile;
            app.UIAxes.Position = [20 20 670 320];
            app.UIAxes2.Position = [20 20 670 320];
            
             
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
%             pathAndFile = getpref('myScriptSettings', lastPathAndFile)
            [file, path] = uigetfile('*.daq', 'Select .daq file', app.FileName.Value);
            myPathAndFile = [path file];
            setpref('myScriptSettings', 'lastPathAndFile', myPathAndFile)
            app.FileName.Value = myPathAndFile;
            stophere = 1;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.z = ReadAndPlotDaqData2Function(app.FileName.Value);
            processData(app);
            calcSpeedCor(app);
            calcSpeed(app);
            
            DisplayButtonGroupSelectionChanged(app);
            FftPulseStrideValueChanged(app, event);
            stophere = 1;
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            stophere = 1;
            if strcmp(selectedButton.Text,'Time Data')
                plotTimeData(app)
            elseif strcmp(selectedButton.Text,'Magnitude Response')
                plotMagnitudeResponse(app)
            elseif strcmp(selectedButton.Text,'Range Max Amplitude')
                plotRangeMaxAmplitude(app)
            end
            
        end

        % Value changed function: FftPulseStride
        function FftPulseStrideValueChanged(app, event)
            value = app.FftPulseStride.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeedCorrelation(app)
        end

        % Value changed function: SpeedFftSize
        function SpeedFftSizeValueChanged(app, event)
            value = app.SpeedFftSize.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeedCorrelation(app)
        end

        % Value changed function: CorrelationThreshold
        function CorrelationThresholdValueChanged(app, event)
            value = app.CorrelationThreshold.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeedCorrelation(app)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.FileName.Editable = 'off';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeMagnitudeTab = uitab(app.TabGroup);
            app.TimeMagnitudeTab.Title = 'Time/Magnitude';
            app.UIAxes = uiaxes(app.TimeMagnitudeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DisplayButtonGroup = uibuttongroup(app.TimeMagnitudeTab);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.TimeDataButton = uiradiobutton(app.DisplayButtonGroup);
            app.TimeDataButton.Text = 'Time Data';
            app.TimeDataButton.Value = true;
            app.MagnitudeResponseButton = uiradiobutton(app.DisplayButtonGroup);
            app.MagnitudeResponseButton.Text = 'Magnitude Response';
            app.RangeMaxAmplitudeButton = uiradiobutton(app.DisplayButtonGroup);
            app.RangeMaxAmplitudeButton.Text = 'Range Max Amplitude';
            app.SpeedCorrelationTab = uitab(app.TabGroup);
            app.SpeedCorrelationTab.Title = 'Speed Correlation';
            app.FFTPulseStrideLabel = uilabel(app.SpeedCorrelationTab);
            app.FFTPulseStrideLabel.Text = 'FFT Pulse Stride';
            app.FftPulseStride = uieditfield(app.SpeedCorrelationTab, 'numeric');
            app.FftPulseStride.Limits = [0 1024];
            app.FftPulseStride.ValueChangedFcn = createCallbackFcn(app, @FftPulseStrideValueChanged, true);
            app.FftPulseStride.Value = 512;
            app.SpeedFFTSizeLabel = uilabel(app.SpeedCorrelationTab);
            app.SpeedFFTSizeLabel.Text = 'Speed FFT Size';
            app.SpeedFftSize = uieditfield(app.SpeedCorrelationTab, 'numeric');
            app.SpeedFftSize.Limits = [16 1024];
            app.SpeedFftSize.ValueChangedFcn = createCallbackFcn(app, @SpeedFftSizeValueChanged, true);
            app.SpeedFftSize.Value = 512;
            app.UIAxes2 = uiaxes(app.SpeedCorrelationTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.CorrelationThresholdEditFieldLabel = uilabel(app.SpeedCorrelationTab);
            app.CorrelationThresholdEditFieldLabel.Text = 'Correlation Threshold';
            app.CorrelationThreshold = uieditfield(app.SpeedCorrelationTab, 'numeric');
            app.CorrelationThreshold.Limits = [0 1];
            app.CorrelationThreshold.ValueChangedFcn = createCallbackFcn(app, @CorrelationThresholdValueChanged, true);
            app.CorrelationThreshold.Value = 0.9;
            app.PlotBins1 = uicheckbox(app.SpeedCorrelationTab);
            app.PlotBins1.Text = 'Plot Bins +- 1';
            app.PlotBins2 = uicheckbox(app.SpeedCorrelationTab);
            app.PlotBins2.Text = 'Plot Bins +- 2';
            app.SpeedCorrelationAllTab = uitab(app.TabGroup);
            app.SpeedCorrelationAllTab.Title = 'Speed Correlation All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Test1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end