classdef Test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileEditFieldLabel          matlab.ui.control.Label
        FileName                    matlab.ui.control.EditField
        SelectFileButton            matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        TimeMagnitudeTab            matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        DisplayButtonGroup          matlab.ui.container.ButtonGroup
        TimeDataButton              matlab.ui.control.RadioButton
        MagnitudeResponseButton     matlab.ui.control.RadioButton
        RangeMaxAmplitudeButton     matlab.ui.control.RadioButton
        ZoomEnabledCheckBox_2       matlab.ui.control.CheckBox
        SpeedTab                    matlab.ui.container.Tab
        FFTPulseStrideLabel         matlab.ui.control.Label
        FftPulseStride              matlab.ui.control.NumericEditField
        SpeedFFTSizeLabel           matlab.ui.control.Label
        SpeedFftSize                matlab.ui.control.NumericEditField
        UIAxes2                     matlab.ui.control.UIAxes
        CorrelationThresholdEditFieldLabel  matlab.ui.control.Label
        CorrelationThreshold        matlab.ui.control.NumericEditField
        PlotBins1                   matlab.ui.control.CheckBox
        PlotBins2                   matlab.ui.control.CheckBox
        PlotDetectionBin            matlab.ui.control.CheckBox
        PlotBins_m1                 matlab.ui.control.CheckBox
        PlotBins_m2                 matlab.ui.control.CheckBox
        RemoveMean                  matlab.ui.control.CheckBox
        ZoomEnabledCheckBox         matlab.ui.control.CheckBox
        CorrelationTab              matlab.ui.container.Tab
        PlotCorrelationBin1         matlab.ui.control.CheckBox
        PlotCorrelationBin2         matlab.ui.control.CheckBox
        PlotCorrelationBin          matlab.ui.control.CheckBox
        PlotCorrelationsBin_m1      matlab.ui.control.CheckBox
        PlotCorrelationBin_m2       matlab.ui.control.CheckBox
        UIAxesCorrelation           matlab.ui.control.UIAxes
        RemoveMean_2                matlab.ui.control.CheckBox
        CorrelationBlockNumSpinnerLabel  matlab.ui.control.Label
        CorrelationBlockNumSpinner  matlab.ui.control.Spinner
        PulseLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        z % container that holds data
        maxFftBin % The range bin where the train is at
        maxFft % max fft data across time
        speedCor % Description
        corMaxMy1 % Description
        corMaxMy2
        corMaxIdxMy
        DIST_BETWEEN_ANTS =(83.32855682e-6); %miles
        hoursPerPrimaryPulse = (279e-6)*2/3600; %hours
        speed
        speed_interp
        pulseIdxs % array containing pulse count indexes for speed correlations
        binDelta = 2; % the number of bins from the max to calculate correlations
    end

    methods (Access = private)
    
        function results = plotTimeData(app)
            title(app.UIAxes, 'Time Data')
            xlabel(app.UIAxes, 'Pulses')
            ylabel(app.UIAxes, 'Magnitude')
            plot(app.UIAxes, squeeze(app.z.dataMatrix(:,1,1:100)))
            set(app.UIAxes, 'xlim', [0 inf])            
        end
        function results = plotMagnitudeResponse(app)
            title(app.UIAxes, ['Magnitude Response - Bin ' num2str(app.maxFftBin)])
            xlabel(app.UIAxes, 'Pulses')
            ylabel(app.UIAxes, 'Magnitude')
            plot(app.UIAxes, squeeze(20*log10(abs(app.z.DataMatrix(app.maxFftBin,1,:)))), 'b')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, squeeze(20*log10(abs(app.z.DataMatrix(app.maxFftBin,2,:)))), 'g')
            hold(app.UIAxes, 'off')
            set(app.UIAxes, 'xlim', [0 size(app.z.DataMatrix,3)])
        end
        function results = plotRangeMaxAmplitude(app)
            title(app.UIAxes, ['Range Max Amplitude - Max Bin '  num2str(app.maxFftBin)])
            xlabel(app.UIAxes, 'Bin')
            ylabel(app.UIAxes, 'Max Amplitude')
            plot(app.UIAxes, 20*log10(app.maxFft))
            set(app.UIAxes, 'xlim', [0 inf])            
        end
        function results = plotSpeed(app)
            title(app.UIAxes2, 'Speed')
            xlabel(app.UIAxes2, 'Pulse')
            ylabel(app.UIAxes2, 'MPH')
            cla(app.UIAxes2, 'reset')
            hold(app.UIAxes2, 'on')
            if app.PlotDetectionBin.Value == 1
                plot(app.UIAxes2, app.pulseIdxs, app.speed(app.maxFftBin,:),'g')
                plot(app.UIAxes2, app.pulseIdxs, app.speed_interp(app.maxFftBin,:),'g--')
            end
            if app.PlotBins1.Value == 1
                plot(app.UIAxes2, app.pulseIdxs, app.speed(app.maxFftBin+1,:),'b')
                plot(app.UIAxes2, app.pulseIdxs, app.speed_interp(app.maxFftBin+1,:),'b--')
            end
            if app.PlotBins2.Value == 1
                plot(app.UIAxes2, app.pulseIdxs, app.speed(app.maxFftBin+2,:),'r')
                plot(app.UIAxes2, app.pulseIdxs, app.speed_interp(app.maxFftBin+2,:),'r--')
            end
            if app.PlotBins_m1.Value == 1
                plot(app.UIAxes2, app.pulseIdxs, app.speed(app.maxFftBin-1,:),'k')
                plot(app.UIAxes2, app.pulseIdxs, app.speed_interp(app.maxFftBin-1,:),'k--')
            end
            if app.PlotBins_m2.Value == 1
                plot(app.UIAxes2, app.pulseIdxs, app.speed(app.maxFftBin-2,:),'c')
                plot(app.UIAxes2, app.pulseIdxs, app.speed_interp(app.maxFftBin-2,:),'c--')
            end
            maxy = 10*ceil(max(abs(app.speed(app.maxFftBin,:)))/10);
            if maxy >100
                maxy =100;
            end
            hold(app.UIAxes2, 'off')
            set(app.UIAxes2, 'ylim', [-maxy maxy])
            set(app.UIAxes2, 'xLimMode', 'manual')
            set(app.UIAxes2, 'xlim', [0 size(app.z.DataMatrix,3)])
        end
      
        function results = plotCorrelation(app)
            blockIdx = app.CorrelationBlockNumSpinner.Value;
            pulse = app.pulseIdxs(blockIdx);
            %xlabel(app.UIAxesCorrelation, 'Pulse')
            %ylabel(app.UIAxesCorrelation, 'MPH')
            cla(app.UIAxesCorrelation, 'reset')
            hold(app.UIAxesCorrelation, 'on')
            if app.PlotCorrelationBin.Value == 1
                plot(app.UIAxesCorrelation, abs(app.speedCor(:,app.maxFftBin+0,blockIdx)),'g')
            end
            if app.PlotCorrelationBin1.Value == 1
                plot(app.UIAxesCorrelation, abs(app.speedCor(:,app.maxFftBin+1,blockIdx)),'b')
            end
            if app.PlotCorrelationBin2.Value == 1
                plot(app.UIAxesCorrelation, abs(app.speedCor(:,app.maxFftBin+2,blockIdx)),'r')
            end
            if app.PlotCorrelationsBin_m1.Value == 1
                plot(app.UIAxesCorrelation, abs(app.speedCor(:,app.maxFftBin-1,blockIdx)),'k')
            end
            if app.PlotCorrelationBin_m2.Value == 1
                plot(app.UIAxesCorrelation, abs(app.speedCor(:,app.maxFftBin-2,blockIdx)),'c')
            end
            maxCorVal = max(max(abs(app.speedCor(:,(app.maxFftBin-app.binDelta):(app.maxFftBin+app.binDelta),blockIdx))));
            maxy = 10*ceil(maxCorVal/10);
            hold(app.UIAxesCorrelation, 'off')
            title(app.UIAxesCorrelation, ['Correlation - Default Bin: ' num2str(app.maxFftBin) '   Pulse: ' num2str(pulse) ' CorrelationBin: ' num2str(app.corMaxIdxMy(app.maxFftBin, blockIdx))])
            set(app.UIAxesCorrelation, 'ylim', [0 maxy])
            set(app.UIAxesCorrelation, 'xLimMode', 'manual')
            set(app.UIAxesCorrelation, 'xlim', [0 size(app.speedCor,1)])
        end

        function results = findMaxBin(app)
            %find bin index for train
            app.maxFft = max(abs(squeeze(app.z.DataMatrix(:,1,:))),[],2);
            starBin = 5;
            [mx, app.maxFftBin] = max(app.maxFft(starBin:end));
            app.maxFftBin = app.maxFftBin + starBin - 1;


        end
        
        function results = calcSpeedCor(app)
            pulseStart = 1;
            pulseStop = size(app.z.DataMatrix,3);
            app.pulseIdxs = pulseStart:app.FftPulseStride.Value:(pulseStop-app.SpeedFftSize.Value);
            app.speedCor = zeros(app.SpeedFftSize.Value, size(app.z.DataMatrix,1), length(app.pulseIdxs));
            app.corMaxMy1 = zeros(size(app.speedCor,2),size(app.pulseIdxs,2));
            app.corMaxMy2 = zeros(size(app.speedCor,2),size(app.pulseIdxs,2));
            app.corMaxIdxMy = zeros(size(app.speedCor,2),size(app.pulseIdxs,2));
            app.speed = zeros(size(app.speedCor,2),size(app.pulseIdxs,2));
            app.speed_interp = zeros(size(app.speedCor,2),size(app.pulseIdxs,2));
            
            
            %% 
            a=0;
            for i = app.pulseIdxs
                a = a+1;
                for bin = (app.maxFftBin-app.binDelta):(app.maxFftBin+app.binDelta)
%                    app.speedCor(:,bin,a)= speedCorrelation(app.z.DataMatrix(bin, 1, i:(i+app.SpeedFftSize.Value-1)), app.z.DataMatrix(bin, 2, i:(i+app.SpeedFftSize.Value-1)));
                    app.speedCor(:,bin,a)= speedCorrelation(squeeze(app.z.DataMatrix(bin, 1, i:(i+app.SpeedFftSize.Value-1))), squeeze(app.z.DataMatrix(bin, 2, i:(i+app.SpeedFftSize.Value-1))),app.RemoveMean.Value);
                    %find largest two peaks 
                    p1 = 0;
                    p1idx = 0;
                    p2 = 0;
                    p2idx = 0;
                    for k = 2:(app.SpeedFftSize.Value-1)
                        if (abs(app.speedCor(k-1,bin,a)) < abs(app.speedCor(k,bin,a))) && (abs(app.speedCor(k,bin,a)) > abs(app.speedCor(k+1,bin,a)))
                            %peak found
                            if abs(app.speedCor(k,bin,a)) > p1
                                p2 = p1;
                                p2idx = p1idx;
                                p1 = abs(app.speedCor(k,bin,a));
                                p1idx = k;
                            elseif abs(app.speedCor(k,bin,a)) > p2
                                p2 = abs(app.speedCor(k,bin,a));
                                p2idx = k;
                            end
                        end
                    end
                    app.corMaxMy1(bin,a) = p1;
                    app.corMaxMy2(bin,a) = p2;
                    if p2 < (app.CorrelationThreshold.Value*p1)
                        app.corMaxIdxMy(bin,a) = p1idx;
                    else
                        app.corMaxIdxMy(bin,a) = nan;
                    end

                end
            end
        end
        
        function results = calcSpeed(app)
            %
            app.speed = zeros(size(app.corMaxIdxMy));
            for i = 1:size(app.corMaxIdxMy,2)
                for bin = (app.maxFftBin-app.binDelta):(app.maxFftBin+app.binDelta)
                    if app.corMaxIdxMy(bin,i) ~= 0 
                        %interpolate
                       icor = app.corMaxIdxMy(bin,i);
                        if app.corMaxIdxMy(bin,i) < app.SpeedFftSize.Value/2
                            icor = app.corMaxIdxMy(bin,i);
                        else
                            icor = app.corMaxIdxMy(bin,i)-app.SpeedFftSize.Value;
                        end
                        app.speed(bin,i) = app.DIST_BETWEEN_ANTS / ((icor +0.5) *app.hoursPerPrimaryPulse);
                        if ~isnan(app.speed(bin,i)) && app.speed(bin,i)~=0
                            icor_interp= icor + (abs(app.speedCor(app.corMaxIdxMy(bin,i)-1,bin,i)) - abs(app.speedCor(app.corMaxIdxMy(bin,i)+1,i))) / ...
                                (2 *(abs(app.speedCor(app.corMaxIdxMy(bin,i)-1,i)) - 2*abs(app.speedCor(app.corMaxIdxMy(bin,i),i)) + abs(app.speedCor(app.corMaxIdxMy(bin,i)+1,i))));
                            app.speed_interp(bin,i) =app.DIST_BETWEEN_ANTS / ((icor_interp +0.5) *app.hoursPerPrimaryPulse);
                        end
                    else
                        %app.speed(bin,i) = 0;
                    end
                end
            end
        
        end


        function results = CorrelationBlockNumSpinnerInit(app)
            app.CorrelationBlockNumSpinner.Limits = [1 length(app.pulseIdxs)];
            CorrelationBlockNumSpinnerValueChanged(app);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                myPathAndFile = getpref('myScriptSettings', 'lastPathAndFile')
            catch ME
               if ME.identifier == "" 
                   myPathAndFile = 'C:\';
                   setpref('myScriptSettings', 'lastPathAndFile', myPathAndFile)
               end
            end
            app.FileName.Value = myPathAndFile;
            app.UIAxes.Position = [20 20 670 320];
            app.UIAxes2.Position = [20 20 670 320];
            %app.ZoomEnabledCheckBox_2.ValueChangedFcn
            %app.ZoomEnabledCheckBox.ValueChangedFcn
            ZoomEnabledCheckBox_2ValueChanged(app);
            ZoomEnabledCheckBoxValueChanged(app);
             
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
%             pathAndFile = getpref('myScriptSettings', lastPathAndFile)
            [file, path] = uigetfile('*.daq', 'Select .daq file', app.FileName.Value);
            myPathAndFile = [path file];
            setpref('myScriptSettings', 'lastPathAndFile', myPathAndFile)
            app.FileName.Value = myPathAndFile;
            stophere = 1;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %app.z = ReadAndPlotDaqData2Function(app.FileName.Value);
            app.z = ReadProcessDaqDataFunction(app.FileName.Value);
            findMaxBin(app);
            calcSpeedCor(app);
            calcSpeed(app);
            
            DisplayButtonGroupSelectionChanged(app);
            FftPulseStrideValueChanged(app, event);
            CorrelationBlockNumSpinnerValueChanged(app, event);
            CorrelationBlockNumSpinnerInit(app);
            plotCorrelation(app);
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            stophere = 1;
            if strcmp(selectedButton.Text,'Time Data')
                plotTimeData(app)
            elseif strcmp(selectedButton.Text,'Magnitude Response')
                plotMagnitudeResponse(app)
            elseif strcmp(selectedButton.Text,'Range Max Amplitude')
                plotRangeMaxAmplitude(app)
            end
            
        end

        % Value changed function: FftPulseStride
        function FftPulseStrideValueChanged(app, event)
            value = app.FftPulseStride.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeed(app)
        end

        % Value changed function: SpeedFftSize
        function SpeedFftSizeValueChanged(app, event)
            value = app.SpeedFftSize.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeed(app)
        end

        % Value changed function: CorrelationThreshold
        function CorrelationThresholdValueChanged(app, event)
            value = app.CorrelationThreshold.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeed(app)

        end

        % Value changed function: PlotBins1
        function PlotBins1ValueChanged(app, event)
            value = app.PlotBins1.Value;
            plotSpeed(app)
        end

        % Value changed function: PlotBins2
        function PlotBins2ValueChanged(app, event)
            value = app.PlotBins2.Value;
            plotSpeed(app)
        end

        % Value changed function: PlotDetectionBin
        function PlotDetectionBinValueChanged(app, event)
            value = app.PlotDetectionBin.Value;
             plotSpeed(app)
        end

        % Value changed function: PlotBins_m1
        function PlotBins_m1ValueChanged(app, event)
            value = app.PlotBins_m1.Value;
            plotSpeed(app)
        end

        % Value changed function: PlotBins_m2
        function PlotBins_m2ValueChanged(app, event)
            value = app.PlotBins_m2.Value;
            plotSpeed(app)
        end

        % Value changed function: RemoveMean
        function RemoveMeanValueChanged(app, event)
            value = app.RemoveMean.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeed(app)
            plotCorrelation(app);
        end

        % Callback function
        function AutoZoomButtonPushed(app, event)
             zoom(app.UIAxes2,'off');
             app.UIAxes2.XLimMode = 'auto';
             app.UIAxes2.YLimMode = 'auto';
        end

        % Callback function
        function AutoZoomButton_2Pushed(app, event)
           
        end

        % Value changed function: ZoomEnabledCheckBox
        function ZoomEnabledCheckBoxValueChanged(app, event)
            value = app.ZoomEnabledCheckBox.Value;
            if value == 1
                pan(app.UIAxes2,'on');
                zoom(app.UIAxes2,'on');
            else
                zoom(app.UIAxes2,'off');
                app.UIAxes2.XLimMode = 'auto';
                app.UIAxes2.YLimMode = 'auto';
                plotSpeed(app)
            end
        end

        % Value changed function: ZoomEnabledCheckBox_2
        function ZoomEnabledCheckBox_2ValueChanged(app, event)
            value = app.ZoomEnabledCheckBox_2.Value;
            if value == 1
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'on');
            else
                zoom(app.UIAxes,'off');
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                DisplayButtonGroupSelectionChanged(app);
            end
            
        end

        % Value changed function: RemoveMean_2
        function RemoveMean_2ValueChanged(app, event)
            value = app.RemoveMean_2.Value;
            calcSpeedCor(app);
            calcSpeed(app);
            plotSpeed(app)
            plotCorrelation(app);
 
        end

        % Value changed function: CorrelationBlockNumSpinner
        function CorrelationBlockNumSpinnerValueChanged(app, event)
            value = app.CorrelationBlockNumSpinner.Value;
            app.PulseLabel.Text = ['Pulse ' num2str(app.pulseIdxs(value))];
            plotCorrelation(app);
        end

        % Value changed function: PlotCorrelationBin
        function PlotCorrelationBinValueChanged(app, event)
            value = app.PlotCorrelationBin.Value;
            plotCorrelation(app);
        end

        % Value changed function: PlotCorrelationBin1
        function PlotCorrelationBin1ValueChanged(app, event)
            value = app.PlotCorrelationBin1.Value;
            plotCorrelation(app);
        end

        % Value changed function: PlotCorrelationBin2
        function PlotCorrelationBin2ValueChanged(app, event)
            value = app.PlotCorrelationBin2.Value;
            plotCorrelation(app);
        end

        % Value changed function: PlotCorrelationsBin_m1
        function PlotCorrelationsBin_m1ValueChanged(app, event)
            value = app.PlotCorrelationsBin_m1.Value;
            plotCorrelation(app);
        end

        % Value changed function: PlotCorrelationBin_m2
        function PlotCorrelationBin_m2ValueChanged(app, event)
            value = app.PlotCorrelationBin_m2.Value;
            plotCorrelation(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.FileName.Editable = 'off';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeMagnitudeTab = uitab(app.TabGroup);
            app.TimeMagnitudeTab.Title = 'Time/Magnitude';
            app.UIAxes = uiaxes(app.TimeMagnitudeTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DisplayButtonGroup = uibuttongroup(app.TimeMagnitudeTab);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.TimeDataButton = uiradiobutton(app.DisplayButtonGroup);
            app.TimeDataButton.Text = 'Time Data';
            app.TimeDataButton.Value = true;
            app.MagnitudeResponseButton = uiradiobutton(app.DisplayButtonGroup);
            app.MagnitudeResponseButton.Text = 'Magnitude Response';
            app.RangeMaxAmplitudeButton = uiradiobutton(app.DisplayButtonGroup);
            app.RangeMaxAmplitudeButton.Text = 'Range Max Amplitude';
            app.ZoomEnabledCheckBox_2 = uicheckbox(app.TimeMagnitudeTab);
            app.ZoomEnabledCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ZoomEnabledCheckBox_2ValueChanged, true);
            app.ZoomEnabledCheckBox_2.Text = 'Zoom Enabled';
            app.ZoomEnabledCheckBox_2.Value = true;
            app.SpeedTab = uitab(app.TabGroup);
            app.SpeedTab.Title = 'Speed';
            app.FFTPulseStrideLabel = uilabel(app.SpeedTab);
            app.FFTPulseStrideLabel.Text = 'FFT Pulse Stride';
            app.FftPulseStride = uieditfield(app.SpeedTab, 'numeric');
            app.FftPulseStride.Limits = [0 4096];
            app.FftPulseStride.ValueChangedFcn = createCallbackFcn(app, @FftPulseStrideValueChanged, true);
            app.FftPulseStride.Value = 512;
            app.SpeedFFTSizeLabel = uilabel(app.SpeedTab);
            app.SpeedFFTSizeLabel.Text = 'Speed FFT Size';
            app.SpeedFftSize = uieditfield(app.SpeedTab, 'numeric');
            app.SpeedFftSize.Limits = [16 4096];
            app.SpeedFftSize.ValueChangedFcn = createCallbackFcn(app, @SpeedFftSizeValueChanged, true);
            app.SpeedFftSize.Value = 512;
            app.UIAxes2 = uiaxes(app.SpeedTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.CorrelationThresholdEditFieldLabel = uilabel(app.SpeedTab);
            app.CorrelationThresholdEditFieldLabel.Text = 'Correlation Threshold';
            app.CorrelationThreshold = uieditfield(app.SpeedTab, 'numeric');
            app.CorrelationThreshold.Limits = [0 1];
            app.CorrelationThreshold.ValueChangedFcn = createCallbackFcn(app, @CorrelationThresholdValueChanged, true);
            app.CorrelationThreshold.Value = 0.9;
            app.PlotBins1 = uicheckbox(app.SpeedTab);
            app.PlotBins1.ValueChangedFcn = createCallbackFcn(app, @PlotBins1ValueChanged, true);
            app.PlotBins1.Text = 'Plot Bin +1';
            app.PlotBins1.Value = true;
            app.PlotBins2 = uicheckbox(app.SpeedTab);
            app.PlotBins2.ValueChangedFcn = createCallbackFcn(app, @PlotBins2ValueChanged, true);
            app.PlotBins2.Text = 'Plot Bin +2';
            app.PlotBins2.Value = true;
            app.PlotDetectionBin = uicheckbox(app.SpeedTab);
            app.PlotDetectionBin.ValueChangedFcn = createCallbackFcn(app, @PlotDetectionBinValueChanged, true);
            app.PlotDetectionBin.Text = 'Plot Detection Bin';
            app.PlotDetectionBin.Value = true;
            app.PlotBins_m1 = uicheckbox(app.SpeedTab);
            app.PlotBins_m1.ValueChangedFcn = createCallbackFcn(app, @PlotBins_m1ValueChanged, true);
            app.PlotBins_m1.Text = 'Plot Bin -1';
            app.PlotBins_m1.Value = true;
            app.PlotBins_m2 = uicheckbox(app.SpeedTab);
            app.PlotBins_m2.ValueChangedFcn = createCallbackFcn(app, @PlotBins_m2ValueChanged, true);
            app.PlotBins_m2.Text = 'Plot Bin -2';
            app.PlotBins_m2.Value = true;
            app.RemoveMean = uicheckbox(app.SpeedTab);
            app.RemoveMean.ValueChangedFcn = createCallbackFcn(app, @RemoveMeanValueChanged, true);
            app.RemoveMean.Text = 'Remove Mean';
            app.RemoveMean.Value = true;
            app.ZoomEnabledCheckBox = uicheckbox(app.SpeedTab);
            app.ZoomEnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomEnabledCheckBoxValueChanged, true);
            app.ZoomEnabledCheckBox.Text = 'Zoom Enabled';
            app.ZoomEnabledCheckBox.Value = true;
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.PlotCorrelationBin1 = uicheckbox(app.CorrelationTab);
            app.PlotCorrelationBin1.ValueChangedFcn = createCallbackFcn(app, @PlotCorrelationBin1ValueChanged, true);
            app.PlotCorrelationBin1.Text = 'Plot Bin +1';
            app.PlotCorrelationBin1.Value = true;
            app.PlotCorrelationBin2 = uicheckbox(app.CorrelationTab);
            app.PlotCorrelationBin2.ValueChangedFcn = createCallbackFcn(app, @PlotCorrelationBin2ValueChanged, true);
            app.PlotCorrelationBin2.Text = 'Plot Bin +2';
            app.PlotCorrelationBin2.Value = true;
            app.PlotCorrelationBin = uicheckbox(app.CorrelationTab);
            app.PlotCorrelationBin.ValueChangedFcn = createCallbackFcn(app, @PlotCorrelationBinValueChanged, true);
            app.PlotCorrelationBin.Text = 'Plot Detection Bin';
            app.PlotCorrelationBin.Value = true;
            app.PlotCorrelationsBin_m1 = uicheckbox(app.CorrelationTab);
            app.PlotCorrelationsBin_m1.ValueChangedFcn = createCallbackFcn(app, @PlotCorrelationsBin_m1ValueChanged, true);
            app.PlotCorrelationsBin_m1.Text = 'Plot Bin -1';
            app.PlotCorrelationsBin_m1.Value = true;
            app.PlotCorrelationBin_m2 = uicheckbox(app.CorrelationTab);
            app.PlotCorrelationBin_m2.ValueChangedFcn = createCallbackFcn(app, @PlotCorrelationBin_m2ValueChanged, true);
            app.PlotCorrelationBin_m2.Text = 'Plot Bin -2';
            app.PlotCorrelationBin_m2.Value = true;
            app.UIAxesCorrelation = uiaxes(app.CorrelationTab);
            title(app.UIAxesCorrelation, 'Title')
            xlabel(app.UIAxesCorrelation, 'X')
            ylabel(app.UIAxesCorrelation, 'Y')
            app.RemoveMean_2 = uicheckbox(app.CorrelationTab);
            app.RemoveMean_2.ValueChangedFcn = createCallbackFcn(app, @RemoveMean_2ValueChanged, true);
            app.RemoveMean_2.Text = 'Remove Mean';
            app.RemoveMean_2.Value = true;
            app.CorrelationBlockNumSpinnerLabel = uilabel(app.CorrelationTab);
            app.CorrelationBlockNumSpinnerLabel.Text = 'Correlation Block Num';
            app.CorrelationBlockNumSpinner = uispinner(app.CorrelationTab);
            app.CorrelationBlockNumSpinner.Limits = [1 Inf];
            app.CorrelationBlockNumSpinner.ValueChangedFcn = createCallbackFcn(app, @CorrelationBlockNumSpinnerValueChanged, true);
            app.CorrelationBlockNumSpinner.Value = 1;
            app.PulseLabel = uilabel(app.CorrelationTab);
            app.PulseLabel.Text = 'Pulse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Test2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end